classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        mangoconditionsLabel          matlab.ui.control.Label
        humidity_inSpinnerLabel       matlab.ui.control.Label
        humidity_inSpinner            matlab.ui.control.Spinner
        humidity_outSpinnerLabel      matlab.ui.control.Label
        humidity_outSpinner           matlab.ui.control.Spinner
        ResetButton                   matlab.ui.control.Button
        airconditionsLabel            matlab.ui.control.Label
        temperature_airCSpinnerLabel  matlab.ui.control.Label
        temperature_airCSpinner       matlab.ui.control.Spinner
        humidity_airSpinnerLabel      matlab.ui.control.Label
        humidity_airSpinner           matlab.ui.control.Spinner
        ResetButton_2                 matlab.ui.control.Button
        ovensettingsLabel             matlab.ui.control.Label
        oven_typeDropDownLabel        matlab.ui.control.Label
        oven_typeDropDown             matlab.ui.control.DropDown
        airflowm3sSpinnerLabel        matlab.ui.control.Label
        airflowm3sSpinner             matlab.ui.control.Spinner
        temperature_inCSpinnerLabel   matlab.ui.control.Label
        temperature_inCSpinner        matlab.ui.control.Spinner
        Image                         matlab.ui.control.Image
        CenterPanel                   matlab.ui.container.Panel
        RightPanel                    matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        def % variable to store the default values
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.def = load('defaults.mat');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.humidity_inSpinner.Value  = app.def.man_w_c_in*100;
            app.humidity_outSpinner.Value = app.def.man_w_c_out*100;
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.temperature_airCSpinner.Value = app.def.s_air_t_cels;
            app.humidity_airSpinner.Value = app.def.s_air_humidity*100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.mangoconditionsLabel = uilabel(app.GridLayout2);
            app.mangoconditionsLabel.Text = 'mango-conditions';
            app.humidity_inSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_inSpinnerLabel.Text = 'humidity_in [%]';
            app.humidity_inSpinner = uispinner(app.GridLayout2);
            app.humidity_inSpinner.Limits = [0 100];
            app.humidity_outSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_outSpinnerLabel.Text = 'humidity_out [%]';
            app.humidity_outSpinner = uispinner(app.GridLayout2);
            app.humidity_outSpinner.Limits = [0 100];
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.airconditionsLabel = uilabel(app.GridLayout2);
            app.airconditionsLabel.Text = 'air-conditions';
            app.temperature_airCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_airCSpinnerLabel.Text = 'temperature_air [°C]';
            app.temperature_airCSpinner = uispinner(app.GridLayout2);
            app.temperature_airCSpinner.Limits = [0 60];
            app.humidity_airSpinnerLabel = uilabel(app.GridLayout2);
            app.humidity_airSpinnerLabel.Text = 'humidity_air [%]';
            app.humidity_airSpinner = uispinner(app.GridLayout2);
            app.humidity_airSpinner.Limits = [0 100];
            app.ResetButton_2 = uibutton(app.GridLayout2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ovensettingsLabel = uilabel(app.GridLayout2);
            app.ovensettingsLabel.Text = 'oven-settings';
            app.oven_typeDropDownLabel = uilabel(app.GridLayout2);
            app.oven_typeDropDownLabel.Text = 'oven_type';
            app.oven_typeDropDown = uidropdown(app.GridLayout2);
            app.oven_typeDropDown.Items = {'ideal', 'heat-pump', 'ATESTA', 'container dryer'};
            app.oven_typeDropDown.Value = 'ideal';
            app.airflowm3sSpinnerLabel = uilabel(app.GridLayout2);
            app.airflowm3sSpinnerLabel.Text = 'air-flow [m3/s]';
            app.airflowm3sSpinner = uispinner(app.GridLayout2);
            app.airflowm3sSpinner.Limits = [0 1000];
            app.temperature_inCSpinnerLabel = uilabel(app.GridLayout2);
            app.temperature_inCSpinnerLabel.Text = 'temperature_in [°C]';
            app.temperature_inCSpinner = uispinner(app.GridLayout2);
            app.temperature_inCSpinner.Limits = [0 90];
            app.Image = uiimage(app.GridLayout2);
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end